classdef figure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ENCRYPTIONANDDECRYPTIONLabel  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        CaesartableTab                matlab.ui.container.Tab
        OutputEditField_4Label        matlab.ui.control.Label
        OutputEditField_4             matlab.ui.control.EditField
        CiphertextLabel               matlab.ui.control.Label
        CiphertextEditField_2         matlab.ui.control.EditField
        PlaintextLabel                matlab.ui.control.Label
        PlaintextEditField_2          matlab.ui.control.EditField
        OutputEditField_3Label        matlab.ui.control.Label
        OutputEditField_3             matlab.ui.control.EditField
        KeyEditField_2                matlab.ui.control.NumericEditField
        KeyEditField_2Label           matlab.ui.control.Label
        KeyEditField                  matlab.ui.control.NumericEditField
        KeyEditFieldLabel             matlab.ui.control.Label
        CAESARTABLELabel              matlab.ui.control.Label
        ENCRYPTButton                 matlab.ui.control.Button
        DECRYPTButton                 matlab.ui.control.Button
        ASCIITable                    matlab.ui.container.Tab
        DecodeButton                  matlab.ui.control.Button
        OutputEditField_2             matlab.ui.control.EditField
        OutputEditField_2Label        matlab.ui.control.Label
        OutputEditField               matlab.ui.control.EditField
        OutputEditFieldLabel          matlab.ui.control.Label
        EncodeButton                  matlab.ui.control.Button
        CiphertextEditField           matlab.ui.control.EditField
        CiphertextEditFieldLabel      matlab.ui.control.Label
        PlaintextEditField            matlab.ui.control.EditField
        PlaintextEditFieldLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ENCRYPTButton
        function ENCRYPTButtonPushed(app, event)
            n=app.KeyEditField.Value;
            M=app.PlaintextEditField_2.Value;
            num = double(M);                        %Converts string into double
num2 = num;
N = n - 95 * fix(n/95);
for i = 1:length(num);  
    if  num(i) + N < 32                 %If ASCII value goes below 32
        num2(i) = 126 - (31- num(i) - N);
    elseif num(i) + N > 126             %If ASCII value goes beyond 126
        num2(i) = 32 + (num(i) + N -127);
    else 
        num2(i) = num(i) + N ;          %If ASCII value goes normal
    end
coded = char(num2);
end
           app.OutputEditField_3.Value=coded;
        end

        % Button pushed function: DECRYPTButton
        function DECRYPTButtonPushed(app, event)
            k=app.KeyEditField_2.Value;
            C=app.CiphertextEditField_2.Value;
            P=double(C)-k;
            l=find(P<65);
            P(l)=P(l)+26;
            l=find(P<97 & P>90);
            P(l)=P(l)+26;
            l=C==32;
            P(l)=32;
            P=char(P);
            app.OutputEditField_4.Value=P;
        end

        % Button pushed function: EncodeButton
        function EncodeButtonPushed(app, event)
            m=app.PlaintextEditField.Value;
            res=double(m);
            app.OutputEditField.Value=num2str(res);
        end

        % Callback function
        function DecryptButtonPushed(app, event)
           
             m=str2double(strsplit(strtrim(app.CiphertextEditField.Value)));
            res=char(m);
            app.OutputEditField_2.Value=(res);
        end

        % Button pushed function: DecodeButton
        function DecodeButtonPushed(app, event)
             m=str2double(strsplit(strtrim(app.CiphertextEditField.Value)));
            res=char(m);
            app.OutputEditField_2.Value=(res);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CaesartableTab = uitab(app.TabGroup);
            app.CaesartableTab.Title = '                                                   Caesar table                                        ';
            app.DECRYPTButton = uibutton(app.CaesartableTab, 'push');
            app.DECRYPTButton.ButtonPushedFcn = createCallbackFcn(app, @DECRYPTButtonPushed, true);
            app.DECRYPTButton.Text = 'DECRYPT';
            app.ENCRYPTButton = uibutton(app.CaesartableTab, 'push');
            app.ENCRYPTButton.ButtonPushedFcn = createCallbackFcn(app, @ENCRYPTButtonPushed, true);
            app.ENCRYPTButton.Text = 'ENCRYPT';
            app.CAESARTABLELabel = uilabel(app.CaesartableTab);
            app.CAESARTABLELabel.Text = '     CAESAR TABLE';
            app.KeyEditFieldLabel = uilabel(app.CaesartableTab);
            app.KeyEditFieldLabel.Text = 'Key';
            app.KeyEditField = uieditfield(app.CaesartableTab, 'numeric');
            app.KeyEditField_2Label = uilabel(app.CaesartableTab);
            app.KeyEditField_2Label.Text = 'Key';
            app.KeyEditField_2 = uieditfield(app.CaesartableTab, 'numeric');
            app.OutputEditField_3 = uieditfield(app.CaesartableTab, 'text');
            app.OutputEditField_3Label = uilabel(app.CaesartableTab);
            app.OutputEditField_3Label.Text = 'Output  ';
            app.PlaintextEditField_2 = uieditfield(app.CaesartableTab, 'text');
            app.PlaintextLabel = uilabel(app.CaesartableTab);
            app.PlaintextLabel.Text = {'Plain  '; '  text   '};
            app.CiphertextEditField_2 = uieditfield(app.CaesartableTab, 'text');
            app.CiphertextLabel = uilabel(app.CaesartableTab);
            app.CiphertextLabel.Text = {'Cipher   '; '  text     '};
            app.OutputEditField_4 = uieditfield(app.CaesartableTab, 'text');
            app.OutputEditField_4Label = uilabel(app.CaesartableTab);
            app.OutputEditField_4Label.Text = 'Output ';
            app.ASCIITable = uitab(app.TabGroup);
            app.ASCIITable.Title = '                                                       ASCII Table                                                       ';
            app.PlaintextEditFieldLabel = uilabel(app.ASCIITable);
            app.PlaintextEditFieldLabel.Text = 'Plain text ';
            app.PlaintextEditField = uieditfield(app.ASCIITable, 'text');
            app.CiphertextEditFieldLabel = uilabel(app.ASCIITable);
            app.CiphertextEditFieldLabel.Text = 'Cipher text';
            app.CiphertextEditField = uieditfield(app.ASCIITable, 'text');
            app.EncodeButton = uibutton(app.ASCIITable, 'push');
            app.EncodeButton.ButtonPushedFcn = createCallbackFcn(app, @EncodeButtonPushed, true);
            app.EncodeButton.Text = 'Encode';
            app.OutputEditFieldLabel = uilabel(app.ASCIITable);
            app.OutputEditFieldLabel.Text = 'Output   ';
            app.OutputEditField = uieditfield(app.ASCIITable, 'text');
            app.OutputEditField_2Label = uilabel(app.ASCIITable);
            app.OutputEditField_2Label.Text = 'Output  ';
            app.OutputEditField_2 = uieditfield(app.ASCIITable, 'text');
            app.DecodeButton = uibutton(app.ASCIITable, 'push');
            app.DecodeButton.ButtonPushedFcn = createCallbackFcn(app, @DecodeButtonPushed, true);
            app.DecodeButton.Text = 'Decode';
            app.ENCRYPTIONANDDECRYPTIONLabel = uilabel(app.UIFigure);
            app.ENCRYPTIONANDDECRYPTIONLabel.Text = 'ENCRYPTION AND DECRYPTION';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = figure

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end